classdef PrototypeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StreamTab                       matlab.ui.container.Tab
        EntersecondsofrecordingSpinnerLabel  matlab.ui.control.Label
        EntersecondsofrecordingSpinner  matlab.ui.control.Spinner
        StreamingModeLampLabel          matlab.ui.control.Label
        StreamingLabel                  matlab.ui.control.Lamp
        RecordButton                    matlab.ui.control.Button
        Message                         matlab.ui.control.Label
        ThunderboardstatusLabel         matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        OutputLabel                     matlab.ui.control.Label
        SpectrogramsTab                 matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        WavFileLoadedTextArea           matlab.ui.control.TextArea
        WavFileLoadedLabel              matlab.ui.control.Label
        PlaythewavfilebrowsedusingtheplaybuttonLabel  matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        SpectrogramMessage              matlab.ui.control.Label
        SpectrogramViewLabel            matlab.ui.control.Label
        SpectrogramBrowse               matlab.ui.control.Button
    end

    
    methods (Access = private)
        % is used to set the audio file chosen by the user from their
        % file explorer
        function setAudioFile(app, fileName)
            % set audio file being used
            global audioFile;
            audioFile = fileName;
        end
        
        % is used to grab the audio file chosen by the user
        function audio = getAudioFile(app)
            global audioFile;
            % audio file to be returned
            audio = audioFile;
        end
        
        % is used to set the number of segments to record and generate
        % based on the user's choice of seconds to record
        function setAudioSegmentNum(app, seconds)
            global audioSegmentNum;
            % each audio segment is four seconds long
            audioSegmentNum = seconds / 4;
        end
        
        % is used to get the number of segments to record and generate
        function num = getAudioSegmentNum(app)
            global audioSegmentNum;
            % return the number of audio segments
            num = audioSegmentNum;
        end
        
        % Looks under the MATLAB folder to find PythonScripts
        % returns path to the folder
        function path = findPathToPythonScripts(app)
            s = what('PythonScripts');
            path = s.path;
            
        end
        
        % different platforms have different path syntaxes,
        % accomodate for that based on the user's platform
        function filePath = configureFilePath(app, fileName)
            % pc has a back slash
            if ispc
                filePath = append('\', fileName);
            % mac or linux has a forward slash
            else
                filePath = append('/', fileName);
            end
            
        end
        
        function setThunderboardPort(app, portStr)
            global port;
            port = portStr;
        end
        
        function portResult = getThunderboardPort(app)
            global port;
            portResult = port;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % get the path to the PythonScripts directory
            path = findPathToPythonScripts(app);
            
            if count(py.sys.path, path) == 0
                insert(py.sys.path,int32(0), path);
            end  
            
            % Check to see if the Thunderboard is connected
            [portStatus, portResult] = system(append(path, configureFilePath(app, 'getPort.py')));

%             Display in Thunderboard status whether or not the board is
%             connected
            if strcmp(portResult,'False')
                app.ThunderboardstatusLabel.Text = 'Thunderboard status: Disconnected';
                drawnow;
            else
                % save the port the Thunderboard is on
                setThunderboardPort(app, portResult);
                app.ThunderboardstatusLabel.Text = 'Thunderboard status: Connected';
                drawnow;
            end
            
        end

        % Button pushed function: SpectrogramBrowse
        function SpectrogramBrowsePushed(app, event)
            % the file explorer will open inside of the Audio folder if it
            % exists
            % user can only look at only .wav files
            [filename, filepath] = uigetfile([pwd '\Audio\*.wav'], 'Select a wav file');
            % the file path of the .wav file the user chose
            fullname = [filepath filename];
            
            % the user canceled or closed the browse window
            if isequal(filename,0)
                % display that no file was selected
                app.WavFileLoadedTextArea.Value = 'File not selected';
            else
                % display the file the user chose
                app.WavFileLoadedTextArea.Value = filename;
                % y = sampled data, fs = sample rate
                [y,fs] = audioread(fullname);
                setAudioFile(app, fullname);
                % let user know the spectrogram is generating
                app.Label.Text = 'Status: Generating spectrogram...';
                drawnow;
                % compute the spectrogram
                spectrogram(y, 256, [], [], fs, 'yaxis');
                % let user know the spectrogram has finished generating
                app.Label.Text = 'Status: Spectrogram generated.';
                drawnow;
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            % save number of audio segments to generate based on seconds
            % inputted
            setAudioSegmentNum(app, app.EntersecondsofrecordingSpinner.Value);
            
            % make the message visible
            app.Message.Visible = 'on';
            % Everytime we push the start recording button clear the message
            app.Message.Text = '';
            
            % get path to python scripts
            pyPath = findPathToPythonScripts(app);
            % initialize variable to hold last part of system command
            testFilePath = configureFilePath(app, 'run.py');
            connectionFilePath = configureFilePath(app, 'tryConnection.py');     
            
            % using either python or python3 to run a script is different
            % based on the user's platform
            pythonType = '';
            if ispc
                pythonType = 'python ';
            % mac and linux
            else
                pythonType = 'python3 ';
            end
            
            % variables testPath and connectionPath hold the full system command 
            testPath = append(pythonType, pyPath, testFilePath);
            connectionPath = append(pythonType, pyPath, connectionFilePath, ' ', getThunderboardPort(app));
            
            % script commands to be run
            testSystemCommand = [testPath];
            connectionSystemCommand = [connectionPath];
            
            % Check to see if the Thunderboard is connected
            [connectionStatus, connectionResult] = system(connectionSystemCommand);
            
            % if the result is True, then the Thunderboard is connected
            if strcmp(connectionResult,'True')
                % Change the Thunderboard status to connected
                app.ThunderboardstatusLabel.Text = 'Thunderboard status: Connected';
                
                % if the user inputs 0 seconds, handle appropriately
                if(app.EntersecondsofrecordingSpinner.Value == 0)
                    app.Message.Visible = 'on';
                    app.Message.Text = {'System Message: Specify the number of seconds'; '                              to record.'};
                    drawnow;
                    % do nothing if 0 seconds is detected
                    return;
                    
                % if the user selects a random number that is not an increment
                % of 4, handle appropriately
                elseif(mod(app.EntersecondsofrecordingSpinner.Value, 4) ~= 0 )
                    app.Message.Visible = 'on';
                    app.Message.Text = 'System Message: Invalid number of seconds.';
                    drawnow;
                    % invalid input so do nothing
                    return;
                end
                
                % Once the record button is pushed, disable it to prevent the
                % user from clicking it multiple times and creating too many
                % wav files than expected
                set(app.RecordButton,'Enable','off');
                
                % run the recording code the specified number of times
                for i = 1:getAudioSegmentNum(app)
                    % Change the lamp color to signal that recording has started
                    app.StreamingLabel.Color = 'red';
                    drawnow;   

                    % run the script, holding the exit code and returned result
                    % in status and result variables respectively
                    app.ThunderboardstatusLabel.Text = 'Thunderboard status: Recording';
                    drawnow;
                    
                    [status, result] = system(testSystemCommand);
                   
                    wavFileNames = result;
                    app.TextArea.Value = [app.TextArea.Value;wavFileNames];
                    
                    % if we get an exit code of 0, everything ran fine
                    % Only show the complete after the last run of the code
                    if status == 0 & i == getAudioSegmentNum(app)
                        % change lamp color to signify recording has stopped
                        app.StreamingLabel.Color = [0.502 0.502 0.502];
                        app.Message.Text = 'System Message: Complete!';
                        app.ThunderboardstatusLabel.Text = 'Thunderboard status: Recording Complete';
                        drawnow;
                        set(app.RecordButton,'Enable','on');
                        setAudioSegmentNum(app, 0);
                    end
                end
                
            % if the result is False, then the Thunderboard is not
            % connected
            elseif strcmp(connectionResult, 'False')
                % Change the Thunderboard status to be disconnected
                app.ThunderboardstatusLabel.Text = 'Thunderboard status: Disconnected';
                % Change the status message to tell the user how to
                % continue
                app.Message.Text = {'System Message: Please plug in the Thunderboard'; '                             to continue.'};
                return;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % get the audio file name that was chosen from browse
            audioFile = getAudioFile(app);
            % read in the wav file
            [y,Fs] = audioread(audioFile);
            % put it into an audioread obj for playing audio
            persistent audio;
            audio = audioplayer(y,Fs);
            % now play the audio
            % blocking because the audioplayer var audio goes out of scope
            % when this function exits
            play(audio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StreamTab = uitab(app.TabGroup);
            app.StreamTab.Title = 'Stream';
            app.OutputLabel = uilabel(app.StreamTab);
            app.OutputLabel.Text = 'Output';
            app.TextArea = uitextarea(app.StreamTab);
            app.TextArea.Editable = 'off';
            app.ThunderboardstatusLabel = uilabel(app.StreamTab);
            app.ThunderboardstatusLabel.Text = 'Thunderboard status: ';
            app.Message = uilabel(app.StreamTab);
            app.Message.Visible = 'off';
            app.Message.Text = '';
            app.RecordButton = uibutton(app.StreamTab, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StreamingLabel = uilamp(app.StreamTab);
            app.StreamingModeLampLabel = uilabel(app.StreamTab);
            app.StreamingModeLampLabel.Text = 'Streaming Mode';
            app.EntersecondsofrecordingSpinner = uispinner(app.StreamTab);
            app.EntersecondsofrecordingSpinner.Step = 4;
            app.EntersecondsofrecordingSpinner.Limits = [0 Inf];
            app.EntersecondsofrecordingSpinnerLabel = uilabel(app.StreamTab);
            app.EntersecondsofrecordingSpinnerLabel.Text = 'Enter seconds of recording';
            app.SpectrogramsTab = uitab(app.TabGroup);
            app.SpectrogramsTab.Title = 'Spectrograms';
            app.SpectrogramBrowse = uibutton(app.SpectrogramsTab, 'push');
            app.SpectrogramBrowse.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramBrowsePushed, true);
            app.SpectrogramBrowse.Text = 'Browse';
            app.SpectrogramViewLabel = uilabel(app.SpectrogramsTab);
            app.SpectrogramViewLabel.Text = 'Spectrogram View';
            app.SpectrogramMessage = uilabel(app.SpectrogramsTab);
            app.SpectrogramMessage.Text = 'Select a wav file to generate its spectrogram using the browse button.';
            app.PlayButton = uibutton(app.SpectrogramsTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PlaythewavfilebrowsedusingtheplaybuttonLabel = uilabel(app.SpectrogramsTab);
            app.PlaythewavfilebrowsedusingtheplaybuttonLabel.Text = 'Play the wav file browsed using the play button.';
            app.WavFileLoadedLabel = uilabel(app.SpectrogramsTab);
            app.WavFileLoadedLabel.Text = 'Wav File Loaded:';
            app.WavFileLoadedTextArea = uitextarea(app.SpectrogramsTab);
            app.WavFileLoadedTextArea.Editable = 'off';
            app.WavFileLoadedTextArea.Value = {'File not selected'};
            app.Label = uilabel(app.SpectrogramsTab);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrototypeGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end